classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ClasificarButton  matlab.ui.control.Button
        EntrenarButton    matlab.ui.control.Button
        HTML              matlab.ui.control.HTML
        Image             matlab.ui.control.Image
    end

    
    properties (Access = public)
        Main_panel  matlab.ui.container.Panel
        Main_menu   matlab.ui.container.Panel
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function ButtonValueChanged(app, event)
              % Cargar el modelo YOLOX cada vez que se hace clic en el botón
                try
                    loadedData = load('trainedDetector_yolox_10e.mat');
                    detector = loadedData.detector;


                    % Leer la imagen para detección
                    imageToDetect = imread(app.Image.ImageSource);  % Cambia a la imagen que prefieras
        
                    % Verificar el tipo de dato de la imagen
                    disp(['Tipo de dato de la imagen antes de conversión: ', class(imageToDetect)]);
                    if ~isa(imageToDetect, 'uint8')
                        imageToDetect = im2uint8(imageToDetect);
                    end
                    disp(['Tipo de dato de la imagen después de conversión: ', class(imageToDetect)]);
        
                    % Realizar la detección en la imagen
                    [bboxes, scores, labels] = detect(detector, imageToDetect, 'Threshold', 0.75, 'AutoResize', false);
                    
                    color = app.BoundingBoxcolorDropDown.Value;
                    % Anotar la imagen con los resultados de detección
                    annotatedImage = insertObjectAnnotation(imageToDetect, "rectangle", bboxes, labels, 'Color', color);
        
                    % Actualizar la imagen en el componente Image
                    app.Image.ImageSource = annotatedImage;
                catch ME
                    % Mostrar un mensaje de error detallado en caso de fallo
                    disp('Error durante la detección:');
                    disp(ME.message);
                end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function: not associated with a component
        function HTMLEventReceived(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            
        end

        % Callback function: HTML, HTML
        function start_button(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            app.Main_panel.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'main_panel.jpg');
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<style>  .my-button {    background-color: blue;    color: white;    padding: 10px 20px;    border: none;    border-radius: 5px;    cursor: pointer;  }</style><button class="my-button">Start</button>';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @start_button, true);
            app.HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @start_button, true);
            app.EntrenarButton = uibutton(app.UIFigure, 'push');
            app.EntrenarButton.Text = 'Entrenar';
            app.ClasificarButton = uibutton(app.UIFigure, 'push');
            app.ClasificarButton.Text = 'Clasificar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end