classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClassificationPanel             matlab.ui.container.Panel
        SiguienteButton                 matlab.ui.control.Image
        AnteriorButton                  matlab.ui.control.Image
        AmpliadaImage                   matlab.ui.control.Image
        StdDevLabel                     matlab.ui.control.Label
        AvgConfidenceLabel              matlab.ui.control.Label
        TotalPeopleLabel                matlab.ui.control.Label
        LoadcustomimageButton           matlab.ui.control.Button
        DownloadImageButton             matlab.ui.control.Button
        HumanThresholdSlider            matlab.ui.control.RangeSlider
        HumanThresholdSliderLabel       matlab.ui.control.Label
        GeneratingboundingboxesLabel    matlab.ui.control.Label
        FindingpeopleinimageLabel       matlab.ui.control.Label
        ReadingimageLabel               matlab.ui.control.Label
        LoadingdetectorLabel            matlab.ui.control.Label
        BoundingBoxcolorDropDown        matlab.ui.control.DropDown
        BoundingBoxcolorDropDownLabel   matlab.ui.control.Label
        DetectButton                    matlab.ui.control.Button
        Image_to_detect                 matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel  matlab.ui.container.Panel
        Humandetector2JNLabel           matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        MENUPanel                       matlab.ui.container.Panel
        ClasificarButton                matlab.ui.control.Button
        EntrenarButton                  matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Model_selector_pannel           matlab.ui.container.Panel
        MyModelsButton                  matlab.ui.control.StateButton
        BackButton_2                    matlab.ui.control.Image
        SelectamodelLabel               matlab.ui.control.Label
        YOLO4Button                     matlab.ui.control.StateButton
        YOLOXButton                     matlab.ui.control.StateButton
        SelectmaindetectorLabel         matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        Image4_2                        matlab.ui.control.Image
        Train_Panel                     matlab.ui.container.Panel
        SelectDirectoryButton           matlab.ui.control.Button
        BackButton                      matlab.ui.control.Image
        ButtonGroup2                    matlab.ui.container.ButtonGroup
        SGDMButton                      matlab.ui.control.RadioButton
        ADAMButton                      matlab.ui.control.RadioButton
        ButtonGroup                     matlab.ui.container.ButtonGroup
        YOLOXButton_3                   matlab.ui.control.RadioButton
        YOLO4Button_3                   matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        VerboseFrequencyEditField       matlab.ui.control.NumericEditField
        VerboseFrequencyEditFieldLabel  matlab.ui.control.Label
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationFrequencyEditFieldLabel  matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        SelectoptionsLabel              matlab.ui.control.Label
        TrainButton                     matlab.ui.control.Button
        Image4_4                        matlab.ui.control.Image
        Yolo4_Hyperparameters_panel     matlab.ui.container.Panel
        BackButton_3                    matlab.ui.control.Image
        SelectyourYOLO4HyperparametersLabel  matlab.ui.control.Label
        SaveandTestButton               matlab.ui.control.Button
        Yolo4OptimizerButtonGroup       matlab.ui.container.ButtonGroup
        SgdmButton                      matlab.ui.control.ToggleButton
        AdamButton                      matlab.ui.control.ToggleButton
        Yolo4MiniBatchSizeButtonGroup   matlab.ui.container.ButtonGroup
        Button_8                        matlab.ui.control.ToggleButton
        Button_7                        matlab.ui.control.ToggleButton
        Yolo4LEarningRateButtonGroup    matlab.ui.container.ButtonGroup
        Button_5                        matlab.ui.control.ToggleButton
        Button_4                        matlab.ui.control.ToggleButton
        Yolo4EpochsoftrainingButtonGroup  matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.ToggleButton
        Button                          matlab.ui.control.ToggleButton
        Yolo4MiniBatchSizeButtonGroup_2  matlab.ui.container.ButtonGroup
        Button_18                       matlab.ui.control.ToggleButton
        Button_17                       matlab.ui.control.ToggleButton
        Yolo4LEarningRateButtonGroup_2  matlab.ui.container.ButtonGroup
        Button_16                       matlab.ui.control.ToggleButton
        Button_15                       matlab.ui.control.ToggleButton
        Yolo4EpochsoftrainingButtonGroup_2  matlab.ui.container.ButtonGroup
        Button_21                       matlab.ui.control.ToggleButton
        Button_20                       matlab.ui.control.ToggleButton
        Button_19                       matlab.ui.control.ToggleButton
        Image4                          matlab.ui.control.Image
        YoloX_Hyperparameters_panel     matlab.ui.container.Panel
        BackButton_4                    matlab.ui.control.Image
        SelectyourYOLOXHyperparametersLabel  matlab.ui.control.Label
        SaveandTest_YoloXButton         matlab.ui.control.Button
        YoloXMiniBatchSizeButtonGroup   matlab.ui.container.ButtonGroup
        Button_11                       matlab.ui.control.ToggleButton
        YoloXLEarningRateButtonGroup    matlab.ui.container.ButtonGroup
        e4Button                        matlab.ui.control.ToggleButton
        YoloXEpochsoftrainingButtonGroup  matlab.ui.container.ButtonGroup
        Button_10                       matlab.ui.control.ToggleButton
        Button_9                        matlab.ui.control.ToggleButton
        YoloXOptimizerButtonGroup       matlab.ui.container.ButtonGroup
        sgdmButton                      matlab.ui.control.ToggleButton
        adamButton                      matlab.ui.control.ToggleButton
        Image4_3                        matlab.ui.control.Image
        MyModels_panel                  matlab.ui.container.Panel
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        BackButton_5                    matlab.ui.control.Image
        Selectoneofyourmodels           matlab.ui.control.Label
        SaveandTestButton_2             matlab.ui.control.Button
        Image4_5                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        modelSelected % Description
        model_id
        ImageDirectory  % Propiedad para almacenar la ruta del directorio de imágenes
        bboxes
        originalImage
        indiceActual
        ImageFilePath
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtomPushed(app, event)
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel.Visible = "off";
            app.MENUPanel.Visible = 'on';
            app.Train_Panel.Visible = "off";
            app.Model_selector_pannel.Visible = "off";
            app.Yolo4_Hyperparameters_panel.Visible = "off";
            app.YoloX_Hyperparameters_panel.Visible = "off";
            app.ClassificationPanel.Visible = "off";
            app.MyModels_panel.Visible = "off";
        end

        % Callback function
        function CLASSIFICATION_Buttom_Pushed(app, event)
            app.MENUPanel.Visible = "off";
            app.ClassificationPanel.Visible = "on";
        end

        % Button pushed function: DetectButton
        function DetectButton_Pushed(app, event)
            % Reiniciar componentes de la interfaz
            app.LoadingdetectorLabel.Visible = "off";
            app.ReadingimageLabel.Visible = "off";
            app.FindingpeopleinimageLabel.Visible = "off";
            app.GeneratingboundingboxesLabel.Visible = "off";
            app.DownloadImageButton.Visible = "off";
            app.AmpliadaImage.Visible = "off";
            app.AnteriorButton.Visible = "off";
            app.SiguienteButton.Visible = "off";
            
            try
                % Cargar el detector
                app.LoadingdetectorLabel.Visible = "on";
                drawnow;
                detector = app.modelSelected;
                
                % Verificar si hay un detector cargado
                if isempty(detector)
                    error('No se ha seleccionado ningún modelo de detección');
                end
        
                % Leer la imagen actual
                app.LoadingdetectorLabel.Visible = "off";
                app.ReadingimageLabel.Visible = "on";
                drawnow;
                
                % Verificar si hay una imagen cargada
                if isempty(app.Image_to_detect.ImageSource)
                    error('No hay imagen cargada para analizar');
                end
                
                imageToDetect = imread(app.Image_to_detect.ImageSource);
                
                % Realizar la detección
                app.ReadingimageLabel.Visible = "off";
                app.FindingpeopleinimageLabel.Visible = "on";
                drawnow;
                
                human_threshold = app.HumanThresholdSlider.Value(1); % Usar solo el valor inferior
                color = app.BoundingBoxcolorDropDown.Value;
                
                % Reiniciar las detecciones anteriores
                app.bboxes = [];
                app.originalImage = imageToDetect;
                
                if app.model_id == 10
                    [bboxes, scores, labels] = detect(detector, imageToDetect, ...
                        'Threshold', human_threshold, 'AutoResize', false);
                else
                    [bboxes, scores, labels] = detect(detector, imageToDetect, ...
                        'Threshold', human_threshold);
                end
                
                % Procesar los resultados solo si se encontraron detecciones
                if ~isempty(bboxes)
                    % Anotar la imagen
                    app.FindingpeopleinimageLabel.Visible = "off";
                    app.GeneratingboundingboxesLabel.Visible = "on";
                    drawnow;
                    
                    annotatedImage = insertObjectAnnotation(imageToDetect, "rectangle", ...
                        bboxes, scores, 'Color', color);
                    
                    % Actualizar la interfaz
                    app.Image_to_detect.ImageSource = annotatedImage;
                    app.bboxes = bboxes;
                    app.originalImage = imageToDetect;
                    app.DownloadImageButton.Visible = "on";
                    
                    % Mostrar primera detección ampliada
                    app.indiceActual = 1;
                    x = bboxes(1, 1);
                    y = bboxes(1, 2);
                    width = bboxes(1, 3);
                    height = bboxes(1, 4);
                    croppedImage = imcrop(imageToDetect, [x y width height]);
                    app.AmpliadaImage.ImageSource = croppedImage;
                    app.AmpliadaImage.Visible = "on";
                    
                    % Habilitar botones de navegación si hay múltiples detecciones
                    if size(bboxes, 1) > 1
                        app.AnteriorButton.Visible = "on";
                        app.SiguienteButton.Visible = "on";
                    end
                    
                    % Actualizar estadísticas
                    numPeople = length(scores);
                    avgScore = mean(scores);
                    stdScore = std(scores);
                    
                    app.TotalPeopleLabel.Text = sprintf('Personas detectadas: %d', numPeople);
                    app.AvgConfidenceLabel.Text = sprintf('Confianza promedio: %.2f', avgScore);
                    app.StdDevLabel.Text = sprintf('Desviación estándar: %.2f', stdScore);
                else
                    % No se encontraron personas
                    app.Image_to_detect.ImageSource = imageToDetect; % Mostrar imagen original
                    app.TotalPeopleLabel.Text = 'Personas detectadas: 0';
                    app.AvgConfidenceLabel.Text = 'Confianza promedio: N/A';
                    app.StdDevLabel.Text = 'Desviación estándar: N/A';
                    uialert(app.UIFigure, 'No se detectaron personas en la imagen', ...
                        'Resultados', 'Icon', 'info');
                end
                
            catch ME
                % Manejar errores
                app.GeneratingboundingboxesLabel.Visible = "off";
                uialert(app.UIFigure, sprintf('Error durante la detección:\n%s', ME.message), ...
                    'Error', 'Icon', 'error');
                
                % Restaurar imagen original si hay error
                if exist('imageToDetect', 'var') && ~isempty(app.Image_to_detect.ImageSource)
                    imageToDetect = imread(app.Image_to_detect.ImageSource);
                    app.Image_to_detect.ImageSource = imageToDetect;
                end
            end
            
            app.GeneratingboundingboxesLabel.Visible = "off";
        
        end

        % Button pushed function: ClasificarButton
        function Classify_Button_pushed(app, event)
            app.MENUPanel.Visible = "off";
            app.Model_selector_pannel.Visible = "on";
        end

        % Value changed function: BoundingBoxcolorDropDown
        function BB_color_changed(app, event)
            value = app.BoundingBoxcolorDropDown.Value;
            
        end

        % Button pushed function: DownloadImageButton
        function Download_button_pushed(app, event)
            try
                % Verificar si hay imagen para guardar
                if isempty(app.Image_to_detect.ImageSource)
                    uialert(app.UIFigure, 'No hay imagen detectada para guardar', 'Error', 'Icon', 'error');
                    return;
                end
                
                % Crear nombre de archivo con marca de tiempo
                timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
                defaultFileName = fullfile(pwd, ['detected_image_' timestamp '.png']);
                
                % Mostrar diálogo para guardar
                [file, path] = uiputfile(...
                    {'*.png','PNG Image (*.png)'; 
                     '*.jpg','JPEG Image (*.jpg)'}, ...
                    'Guardar imagen detectada', ...
                    defaultFileName);
                
                % Verificar si el usuario canceló
                if isequal(file, 0) || isequal(path, 0)
                    return;
                end
                
                % Construir ruta completa
                fullPath = fullfile(path, file);
                
                % Obtener los datos de la imagen CORRECTAMENTE
                if ischar(app.Image_to_detect.ImageSource)
                    % Si es una ruta de archivo
                    imgData = imread(app.Image_to_detect.ImageSource);
                else
                    % Si es una matriz de imagen
                    imgData = app.Image_to_detect.ImageSource;
                end
                
                % Guardar en el formato adecuado
                [~, ~, ext] = fileparts(fullPath);
                switch lower(ext)
                    case {'.jpg', '.jpeg'}
                        imwrite(imgData, fullPath, 'Quality', 90);
                    otherwise % PNG por defecto
                        imwrite(imgData, fullPath);
                end
                
                % Mostrar confirmación
                uialert(app.UIFigure, sprintf('Imagen guardada exitosamente en:\n%s', fullPath), ...
                       'Éxito', 'Icon', 'success');
                
            catch ME
                % Manejar errores
                uialert(app.UIFigure, ...
                       sprintf('Error al guardar la imagen:\n%s', ME.message), ...
                       'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: LoadcustomimageButton
        function Load_custom_image_pushed(app, event)
           % Definir los filtros de archivo
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            

            [file, path] = uigetfile(filterspec);

            % Verificar si el usuario canceló la selección
            if isequal(file, 0) || isequal(path, 0)
                % Mostrar mensaje opcional (puedes eliminarlo si prefieres silencio)
                message = 'No se seleccionó ninguna imagen.';
                uialert(app.UIFigure, message, 'Operación cancelada', 'Icon', 'info');
                return;
            end

            fullFilePath = fullfile(path, file);
        
            % Actualizar la imagen
            app.Image_to_detect.ImageSource = fullFilePath;
            

        end

        % Callback function
        function model_selection_pushed(app, event)


        end

        % Button pushed function: SaveandTestButton
        function yolo4_hyperparameters_pushed(app, event)
            % Acceder a los objetos seleccionados 
            optimizer = app.Yolo4OptimizerButtonGroup.SelectedObject;

            if strcmp(optimizer.Text, 'Adam')
                epochs = app.Yolo4EpochsoftrainingButtonGroup.SelectedObject;
                lr = app.Yolo4LEarningRateButtonGroup.SelectedObject;
                mb = app.Yolo4MiniBatchSizeButtonGroup.SelectedObject;
            else
                epochs = app.Yolo4EpochsoftrainingButtonGroup_2.SelectedObject;
                lr = app.Yolo4LEarningRateButtonGroup_2.SelectedObject;
                mb = app.Yolo4MiniBatchSizeButtonGroup_2.SelectedObject;
            end
            
            resultString = fullfile(pwd, 'Modelos', sprintf('trainedDetector_yolo4_%s_%se_%slr_%smb.mat', ...
                            lower(optimizer.Text), epochs.Text, lr.Text, mb.Text));

            loadedData = load(resultString);
            app.modelSelected= loadedData.detector;
            
            app.Yolo4_Hyperparameters_panel.Visible = "off";
            app.ClassificationPanel.Visible = "on";
        end

        % Button pushed function: NextButton
        function main_model_selected_pushed(app, event)
            if app.YOLOXButton.Value == 1 && app.YOLO4Button.Value == 0 && app.MyModelsButton.Value == 0
                disp("hiii");
                app.Model_selector_pannel.Visible = "off";
                app.YoloX_Hyperparameters_panel.Visible = "on";
                app.model_id = 10;
            elseif app.YOLO4Button.Value == 1 && app.YOLOXButton.Value == 0 && app.MyModelsButton.Value == 0
                disp("hooo");
                app.Model_selector_pannel.Visible = "off";
                app.Yolo4_Hyperparameters_panel.Visible = "on";
                app.model_id = 4;
            elseif app.MyModelsButton.Value == 1 && app.YOLO4Button.Value == 0 && app.YOLOXButton.Value == 0
                % Ruta de la carpeta "entrenados"
                trainedModelsFolder = fullfile(pwd, 'Modelos', 'entrenados');

                if ~isfolder(trainedModelsFolder)
                    uialert(app.UIFigure, 'No se encontraron modelos entrenados.', 'Error');
                    return;
                end
                
                modelFiles = dir(fullfile(trainedModelsFolder, '*.mat'));
                modelNames = {modelFiles.name};
                
                if isempty(modelNames)
                    uialert(app.UIFigure, 'No se encontraron modelos entrenados.', 'Advertencia');
                    return;
                end
                
                % Llena el DropDown o ListBox con los nombres de los modelos
                app.ModelDropDown.Items = modelNames;
                app.Model_selector_pannel.Visible = "off";
                app.MyModels_panel.Visible = "on";
            else
                app.SelectamodelLabel.Visible = "on";
            end

        end

        % Button pushed function: SaveandTest_YoloXButton
        function yoloX_hyperparameters_pushed(app, event)
            % Acceder a los objetos seleccionados 
            optimizer = app.YoloXOptimizerButtonGroup.SelectedObject;
            epochs = app.YoloXEpochsoftrainingButtonGroup.SelectedObject;
            lr = app.YoloXLEarningRateButtonGroup.SelectedObject;
            mb = app.YoloXMiniBatchSizeButtonGroup.SelectedObject;
            
            resultString = fullfile(pwd, 'Modelos', sprintf('trainedDetector_yolox_%s_%se_%slr_%smb.mat', ...
                            lower(optimizer.Text), epochs.Text, lr.Text, mb.Text));

            loadedData = load(resultString);
            app.modelSelected= loadedData.detector;
            
            app.YoloX_Hyperparameters_panel.Visible = "off";
            app.ClassificationPanel.Visible = "on";
        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            app.MENUPanel.Visible = "off";
            app.Train_Panel.Visible = "on";
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % Verifica si se seleccionó un directorio de imágenes
            if isempty(app.ImageDirectory)
                uialert(app.UIFigure, 'Por favor, selecciona un directorio de imágenes antes de entrenar.', 'Error');
                return;
            end
            
            % Verificar que el directorio contenga imágenes válidas (jpg, png, etc.)
            validExtensions = {'.jpg', '.jpeg', '.png', '.tif', '.tiff'};
            imageFiles = [];
            for i = 1:length(validExtensions)
                imageFiles = [imageFiles; dir(fullfile(app.ImageDirectory, ['*' validExtensions{i}]))];
            end
            
            if isempty(imageFiles)
                uialert(app.UIFigure, ['El directorio seleccionado no contiene imágenes válidas (' strjoin(validExtensions, ', ') ']. ' ...
                    'Por favor, seleccione otro directorio.'], 'Error');
                app.Train_Panel.Visible = "on"; % Mantener en pantalla de entrenamiento
                app.ClassificationPanel.Visible = "off"; % Evitar pasar a clasificación
                return;
            end
            
            % Verificar que exista el archivo preparedData.mat
            preparedDataPath = fullfile(app.ImageDirectory, 'preparedData.mat');
            if ~isfile(preparedDataPath)
                uialert(app.UIFigure, 'El directorio seleccionado no contiene el archivo preparedData.mat necesario para el entrenamiento.', 'Error');
                app.Train_Panel.Visible = "on";
                app.ClassificationPanel.Visible = "off";
                return;
            end
            
            % Crear barra de progreso
            dlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Entrenando...', ...
                'Message', 'Por favor espera mientras la red neuronal se entrena...', ...
                'Cancelable', 'off');
        
            try
                % Obtén los valores seleccionados por el usuario
                networkType = app.ButtonGroup.SelectedObject.Text;  % YOLO 4 o YOLO X
                optimizerType = app.ButtonGroup2.SelectedObject.Text;  % ADAM o SGDM
        
                switch optimizerType
                    case 'ADAM'
                        optimizerType = 'adam';
                    case 'SGDM'
                        optimizerType = 'sgdm';
                end
                
                % Obtén parámetros adicionales desde los campos de texto
                maxEpochs = app.MaxEpochsEditField.Value;
                initialLearnRate = app.InitialLearnRateEditField.Value;
                miniBatchSize = app.MiniBatchSizeEditField.Value;
                verboseFrequency = app.VerboseFrequencyEditField.Value;
                validationFrequency = app.ValidationFrequencyEditField.Value;
        
                % Cargar los datos preparados
                data = load(preparedDataPath);
                trainingData = data.trainingData;
                validationData = data.validationData;
                anchorBoxes = data.anchorBoxes;
                inputSize = data.inputSize;
        
                % Actualizar rutas en trainingData
                trainingFiles = trainingData.UnderlyingDatastores{1,1}.Files;
                for i = 1:numel(trainingFiles)
                    [~, fileName, fileExt] = fileparts(trainingFiles{i});
                    trainingFiles{i} = fullfile(app.ImageDirectory, [fileName, fileExt]);
                end
                trainingData.UnderlyingDatastores{1,1}.Files = trainingFiles;
                
                % Actualizar rutas en validationData
                validationFiles = validationData.UnderlyingDatastores{1,1}.Files;
                for i = 1:numel(validationFiles)
                    [~, fileName, fileExt] = fileparts(validationFiles{i});
                    validationFiles{i} = fullfile(app.ImageDirectory, [fileName, fileExt]);
                end
                validationData.UnderlyingDatastores{1,1}.Files = validationFiles;
                            
                % Configurar opciones de entrenamiento
                options = trainingOptions(optimizerType, ...
                    'InitialLearnRate', initialLearnRate, ...
                    'MiniBatchSize', miniBatchSize, ...
                    'MaxEpochs', maxEpochs, ...
                    'VerboseFrequency', verboseFrequency, ...
                    'ValidationData', validationData, ...
                    'ValidationFrequency', validationFrequency, ...
                    'DispatchInBackground', true, ...
                    'Plots', 'training-progress', ...
                    'OutputFcn', @(info) updateProgress(info, dlg, maxEpochs));
        
                % Configurar el detector YOLO según la opción seleccionada
                switch networkType
                    case 'YOLO 4'
                        detector = yolov4ObjectDetector("tiny-yolov4-coco", "annotations", anchorBoxes, InputSize=inputSize);
                        trainedDetector = trainYOLOv4ObjectDetector(trainingData, detector, options);
                    case 'YOLO X'
                        detector = yoloxObjectDetector("small-coco", "annotations", InputSize=[512 512 3]);
                        trainedDetector = trainYOLOXObjectDetector(trainingData, detector, options);
                end
                
                % Guardar el modelo entrenado
                modelFileName = sprintf('trainedDetector_%s_%s_%se_%slr_%smb.mat', ...
                                      lower(strrep(networkType, ' ', '')), ...
                                      lower(optimizerType), ...
                                      num2str(maxEpochs), ...
                                      num2str(initialLearnRate), ...
                                      num2str(miniBatchSize));
        
                % Ruta para la carpeta "entrenados"
                trainedModelsFolder = fullfile(pwd, 'Modelos', 'entrenados');
                if ~isfolder(trainedModelsFolder)
                    mkdir(trainedModelsFolder);
                end
        
                % Guardar el modelo
                modelFilePath = fullfile(trainedModelsFolder, modelFileName);
                save(modelFilePath, 'trainedDetector');
                
                % Cargar automáticamente el modelo entrenado
                loadedData = load(modelFilePath);
                app.modelSelected = loadedData.trainedDetector;
                
                % Mostrar mensaje de éxito
                uialert(app.UIFigure, sprintf('¡Entrenamiento completado!\nModelo guardado como:\n%s', modelFileName), 'Éxito');
                
                % Solo cambiar a clasificación si todo fue exitoso
                app.Train_Panel.Visible = "off";
                app.ClassificationPanel.Visible = "on";
                
            catch ME
                % Mostrar error detallado
                uialert(app.UIFigure, sprintf('Error durante el entrenamiento:\n%s', ME.message), 'Error');
                app.Train_Panel.Visible = "on"; % Mantener en pantalla de entrenamiento
                app.ClassificationPanel.Visible = "off";
            end
        
            % Cerrar la barra de progreso
            if isvalid(dlg)
                close(dlg);
            end
        
            % Función para actualizar la barra de progreso
            function stop = updateProgress(info, dlg, maxEpochs)
                stop = false;
                if ~isempty(info.State) && strcmp(info.State, 'iteration')
                    dlg.Value = info.Epoch / maxEpochs;
                    dlg.Message = sprintf('Entrenando (época %d/%d)...', info.Epoch, maxEpochs);
                end
            end

        end

        % Image clicked function: BackButton
        function BackButtonImageClicked(app, event)
            app.Train_Panel.Visible = "off";
            app.MENUPanel.Visible = "on";
        end

        % Image clicked function: BackButton_3
        function BackButton_3ImageClicked(app, event)
            app.Yolo4_Hyperparameters_panel.Visible = "off";
            app.Model_selector_pannel.Visible = "on";
        end

        % Image clicked function: BackButton_2
        function BackButton_2ImageClicked(app, event)
            app.Model_selector_pannel.Visible = "off";
            app.MENUPanel.Visible = "on";
        end

        % Image clicked function: BackButton_4
        function BackButton_4ImageClicked(app, event)
            app.YoloX_Hyperparameters_panel.Visible = "off";
            app.Model_selector_pannel.Visible = "on";
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            % Abrir el cuadro de diálogo para seleccionar un directorio
            selectedDir = uigetdir(pwd, 'Selecciona el directorio de las imágenes');
            
            if selectedDir ~= 0  % Si el usuario no cancela la selección
                app.ImageDirectory = selectedDir;  % Guardar el directorio seleccionado en una propiedad del app
            else
                uialert(app.UIFigure, 'No se seleccionó ningún directorio.', 'Aviso');
            end
        end

        % Value changed function: MyModelsButton
        function MyModelsButtonValueChanged(app, event)

            
        end

        % Image clicked function: BackButton_5
        function BackButton_5ImageClicked(app, event)
            app.MyModels_panel.Visible = "off";
            app.Model_selector_pannel.Visible = "on";
        end

        % Button pushed function: SaveandTestButton_2
        function SaveandTestButton_2Pushed(app, event)
            % Obtener el modelo seleccionado del DropDown
            selectedModel = app.ModelDropDown.Value;
            
            trainedModelsFolder = fullfile(pwd, 'Modelos', 'entrenados');
            modelFilePath = fullfile(trainedModelsFolder, selectedModel);
            
            try
                loadedData = load(modelFilePath);

                if isfield(loadedData, 'trainedDetector')
                    app.modelSelected = loadedData.trainedDetector;
                else
                    uialert(app.UIFigure, 'El archivo seleccionado no contiene un modelo válido.', 'Error');
                end
            catch ME
                uialert(app.UIFigure, sprintf('Error al cargar el modelo: %s', ME.message), 'Error');
            end
            
            app.MyModels_panel.Visible = "off";
            app.ClassificationPanel.Visible = "on";
        end

        % Selection changed function: Yolo4OptimizerButtonGroup
        function Yolo4OptimizerButtonGroupSelectionChanged(app, event)
            selectedButton = app.Yolo4OptimizerButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Adam')
                % Ocultar los grupos de botones
                app.Yolo4MiniBatchSizeButtonGroup.Visible = 'on';
                app.Yolo4LEarningRateButtonGroup.Visible = 'on';
                app.Yolo4EpochsoftrainingButtonGroup.Visible = 'on';
                app.Yolo4MiniBatchSizeButtonGroup_2.Visible = 'off';
                app.Yolo4LEarningRateButtonGroup_2.Visible = 'off';
                app.Yolo4EpochsoftrainingButtonGroup_2.Visible = 'off';
            else
                % Mostrar los grupos de botones
                app.Yolo4MiniBatchSizeButtonGroup_2.Visible = 'on';
                app.Yolo4LEarningRateButtonGroup_2.Visible = 'on';
                app.Yolo4EpochsoftrainingButtonGroup_2.Visible = 'on';
                app.Yolo4MiniBatchSizeButtonGroup.Visible = 'off';
                app.Yolo4LEarningRateButtonGroup.Visible = 'off';
                app.Yolo4EpochsoftrainingButtonGroup.Visible = 'off';
            end
        end

        % Image clicked function: SiguienteButton
        function SiguienteButtonImageClicked(app, event)
            if app.indiceActual < size(app.bboxes, 1)
                indice = app.indiceActual + 1;
                imageToDetect = app.originalImage;
                bboxes = app.bboxes;
        
                % Verificar que el índice sea válido
                if indice < 1 || indice > size(bboxes, 1)
                    return;  % Salir si el índice no es válido
                end
        
                % Obtener las coordenadas de la bbox
                x = bboxes(indice, 1);
                y = bboxes(indice, 2);
                width = bboxes(indice, 3);
                height = bboxes(indice, 4);
        
                % Recortar la región de la imagen
                croppedImage = imcrop(imageToDetect, [x y width height]);
        
                % Mostrar la región ampliada en el componente AmpliadaImage
                app.AmpliadaImage.ImageSource = croppedImage;
        
                % Actualizar el índice actual
                app.indiceActual = indice;
        
                % Habilitar botones de navegación si hay más de una región
                if size(bboxes, 1) > 1
                    app.AnteriorButton.Visible = "on";
                    app.SiguienteButton.Visible = "on";
                else
                    app.AnteriorButton.Visible = "off";
                    app.SiguienteButton.Visible = "off";
                end
            end
        end

        % Image clicked function: AnteriorButton
        function AnteriorButtonImageClicked(app, event)
            if app.indiceActual > 1
                indice = app.indiceActual - 1;
                imageToDetect = app.originalImage;
                bboxes = app.bboxes;
        
                % Verificar que el índice sea válido
                if indice < 1 || indice > size(bboxes, 1)
                    return;  % Salir si el índice no es válido
                end
        
                % Obtener las coordenadas de la bbox
                x = bboxes(indice, 1);
                y = bboxes(indice, 2);
                width = bboxes(indice, 3);
                height = bboxes(indice, 4);
        
                % Recortar la región de la imagen
                croppedImage = imcrop(imageToDetect, [x y width height]);
        
                % Mostrar la región ampliada en el componente AmpliadaImage
                app.AmpliadaImage.ImageSource = croppedImage;
        
                % Actualizar el índice actual
                app.indiceActual = indice;
        
                % Habilitar botones de navegación si hay más de una región
                if size(bboxes, 1) > 1
                    app.AnteriorButton.Visible = "on";
                    app.SiguienteButton.Visible = "on";
                else
                    app.AnteriorButton.Visible = "off";
                    app.SiguienteButton.Visible = "off";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MyModels_panel = uipanel(app.UIFigure);
            app.Image4_5 = uiimage(app.MyModels_panel);
            app.Image4_5.ImageSource = fullfile(pathToMLAPP, 'images', 'landscape-background-mountains-with-sunset-illustration.jpg');
            app.SaveandTestButton_2 = uibutton(app.MyModels_panel, 'push');
            app.SaveandTestButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveandTestButton_2Pushed, true);
            app.SaveandTestButton_2.Text = 'Save and Test';
            app.Selectoneofyourmodels = uilabel(app.MyModels_panel);
            app.Selectoneofyourmodels.Text = 'Select  one of your models';
            app.BackButton_5 = uiimage(app.MyModels_panel);
            app.BackButton_5.ImageClickedFcn = createCallbackFcn(app, @BackButton_5ImageClicked, true);
            app.BackButton_5.ImageSource = fullfile(pathToMLAPP, 'images', 'atras.png');
            app.ModelDropDownLabel = uilabel(app.MyModels_panel);
            app.ModelDropDownLabel.Text = 'Model Drop Down';
            app.ModelDropDown = uidropdown(app.MyModels_panel);
            app.YoloX_Hyperparameters_panel = uipanel(app.UIFigure);
            app.YoloX_Hyperparameters_panel.Visible = 'off';
            app.Image4_3 = uiimage(app.YoloX_Hyperparameters_panel);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'images', 'landscape-background-mountains-with-sunset-illustration.jpg');
            app.YoloXOptimizerButtonGroup = uibuttongroup(app.YoloX_Hyperparameters_panel);
            app.YoloXOptimizerButtonGroup.Title = 'Yolo X Optimizer';
            app.adamButton = uitogglebutton(app.YoloXOptimizerButtonGroup);
            app.adamButton.Text = 'adam';
            app.adamButton.Value = true;
            app.sgdmButton = uitogglebutton(app.YoloXOptimizerButtonGroup);
            app.sgdmButton.Text = 'sgdm';
            app.YoloXEpochsoftrainingButtonGroup = uibuttongroup(app.YoloX_Hyperparameters_panel);
            app.YoloXEpochsoftrainingButtonGroup.Title = 'Yolo X Epochs of training';
            app.Button_9 = uitogglebutton(app.YoloXEpochsoftrainingButtonGroup);
            app.Button_9.Text = '5';
            app.Button_9.Value = true;
            app.Button_10 = uitogglebutton(app.YoloXEpochsoftrainingButtonGroup);
            app.Button_10.Text = '10';
            app.YoloXLEarningRateButtonGroup = uibuttongroup(app.YoloX_Hyperparameters_panel);
            app.YoloXLEarningRateButtonGroup.Title = 'Yolo X LEarning Rate';
            app.e4Button = uitogglebutton(app.YoloXLEarningRateButtonGroup);
            app.e4Button.Text = '5e-4';
            app.e4Button.Value = true;
            app.YoloXMiniBatchSizeButtonGroup = uibuttongroup(app.YoloX_Hyperparameters_panel);
            app.YoloXMiniBatchSizeButtonGroup.Title = 'Yolo X Mini Batch Size';
            app.Button_11 = uitogglebutton(app.YoloXMiniBatchSizeButtonGroup);
            app.Button_11.Text = '10';
            app.Button_11.Value = true;
            app.SaveandTest_YoloXButton = uibutton(app.YoloX_Hyperparameters_panel, 'push');
            app.SaveandTest_YoloXButton.ButtonPushedFcn = createCallbackFcn(app, @yoloX_hyperparameters_pushed, true);
            app.SaveandTest_YoloXButton.Text = 'Save and Test';
            app.SelectyourYOLOXHyperparametersLabel = uilabel(app.YoloX_Hyperparameters_panel);
            app.SelectyourYOLOXHyperparametersLabel.Text = 'Select  your YOLO X Hyperparameters';
            app.BackButton_4 = uiimage(app.YoloX_Hyperparameters_panel);
            app.BackButton_4.ImageClickedFcn = createCallbackFcn(app, @BackButton_4ImageClicked, true);
            app.BackButton_4.ImageSource = fullfile(pathToMLAPP, 'images', 'atras.png');
            app.Yolo4_Hyperparameters_panel = uipanel(app.UIFigure);
            app.Yolo4_Hyperparameters_panel.Visible = 'off';
            app.Image4 = uiimage(app.Yolo4_Hyperparameters_panel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', 'landscape-background-mountains-with-sunset-illustration.jpg');
            app.Yolo4EpochsoftrainingButtonGroup_2 = uibuttongroup(app.Yolo4_Hyperparameters_panel);
            app.Yolo4EpochsoftrainingButtonGroup_2.Title = 'Yolo 4 Epochs of training';
            app.Button_19 = uitogglebutton(app.Yolo4EpochsoftrainingButtonGroup_2);
            app.Button_19.Text = '50';
            app.Button_20 = uitogglebutton(app.Yolo4EpochsoftrainingButtonGroup_2);
            app.Button_20.Text = '25';
            app.Button_20.Value = true;
            app.Button_21 = uitogglebutton(app.Yolo4EpochsoftrainingButtonGroup_2);
            app.Button_21.Text = '75';
            app.Yolo4LEarningRateButtonGroup_2 = uibuttongroup(app.Yolo4_Hyperparameters_panel);
            app.Yolo4LEarningRateButtonGroup_2.Title = 'Yolo 4 LEarning Rate';
            app.Button_15 = uitogglebutton(app.Yolo4LEarningRateButtonGroup_2);
            app.Button_15.Text = '0.0001';
            app.Button_15.Value = true;
            app.Button_16 = uitogglebutton(app.Yolo4LEarningRateButtonGroup_2);
            app.Button_16.Text = '0.0002';
            app.Yolo4MiniBatchSizeButtonGroup_2 = uibuttongroup(app.Yolo4_Hyperparameters_panel);
            app.Yolo4MiniBatchSizeButtonGroup_2.Title = 'Yolo 4 Mini Batch Size';
            app.Button_17 = uitogglebutton(app.Yolo4MiniBatchSizeButtonGroup_2);
            app.Button_17.Text = '16';
            app.Button_17.Value = true;
            app.Button_18 = uitogglebutton(app.Yolo4MiniBatchSizeButtonGroup_2);
            app.Button_18.Text = '8';
            app.Yolo4EpochsoftrainingButtonGroup = uibuttongroup(app.Yolo4_Hyperparameters_panel);
            app.Yolo4EpochsoftrainingButtonGroup.Title = 'Yolo 4 Epochs of training';
            app.Button = uitogglebutton(app.Yolo4EpochsoftrainingButtonGroup);
            app.Button.Text = '50';
            app.Button.Value = true;
            app.Button_3 = uitogglebutton(app.Yolo4EpochsoftrainingButtonGroup);
            app.Button_3.Text = '25';
            app.Yolo4LEarningRateButtonGroup = uibuttongroup(app.Yolo4_Hyperparameters_panel);
            app.Yolo4LEarningRateButtonGroup.Title = 'Yolo 4 LEarning Rate';
            app.Button_4 = uitogglebutton(app.Yolo4LEarningRateButtonGroup);
            app.Button_4.Text = '0.001';
            app.Button_4.Value = true;
            app.Button_5 = uitogglebutton(app.Yolo4LEarningRateButtonGroup);
            app.Button_5.Text = '0.0015';
            app.Yolo4MiniBatchSizeButtonGroup = uibuttongroup(app.Yolo4_Hyperparameters_panel);
            app.Yolo4MiniBatchSizeButtonGroup.Title = 'Yolo 4 Mini Batch Size';
            app.Button_7 = uitogglebutton(app.Yolo4MiniBatchSizeButtonGroup);
            app.Button_7.Text = '6';
            app.Button_7.Value = true;
            app.Button_8 = uitogglebutton(app.Yolo4MiniBatchSizeButtonGroup);
            app.Button_8.Text = '8';
            app.Yolo4OptimizerButtonGroup = uibuttongroup(app.Yolo4_Hyperparameters_panel);
            app.Yolo4OptimizerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Yolo4OptimizerButtonGroupSelectionChanged, true);
            app.Yolo4OptimizerButtonGroup.Title = 'Yolo 4 Optimizer';
            app.AdamButton = uitogglebutton(app.Yolo4OptimizerButtonGroup);
            app.AdamButton.Text = 'Adam';
            app.AdamButton.Value = true;
            app.SgdmButton = uitogglebutton(app.Yolo4OptimizerButtonGroup);
            app.SgdmButton.Text = 'Sgdm';
            app.SaveandTestButton = uibutton(app.Yolo4_Hyperparameters_panel, 'push');
            app.SaveandTestButton.ButtonPushedFcn = createCallbackFcn(app, @yolo4_hyperparameters_pushed, true);
            app.SaveandTestButton.Text = 'Save and Test';
            app.SelectyourYOLO4HyperparametersLabel = uilabel(app.Yolo4_Hyperparameters_panel);
            app.SelectyourYOLO4HyperparametersLabel.Text = 'Select  your YOLO 4 Hyperparameters';
            app.BackButton_3 = uiimage(app.Yolo4_Hyperparameters_panel);
            app.BackButton_3.ImageClickedFcn = createCallbackFcn(app, @BackButton_3ImageClicked, true);
            app.BackButton_3.ImageSource = fullfile(pathToMLAPP, 'images', 'atras.png');
            app.Train_Panel = uipanel(app.UIFigure);
            app.Image4_4 = uiimage(app.Train_Panel);
            app.Image4_4.ImageSource = fullfile(pathToMLAPP, 'images', 'landscape-background-mountains-with-sunset-illustration.jpg');
            app.TrainButton = uibutton(app.Train_Panel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.SelectoptionsLabel = uilabel(app.Train_Panel);
            app.SelectoptionsLabel.Text = 'Select options';
            app.Panel = uipanel(app.Train_Panel);
            app.Panel.Title = 'Panel';
            app.MaxEpochsEditFieldLabel = uilabel(app.Panel);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.Panel, 'numeric');
            app.MaxEpochsEditField.Limits = [0 Inf];
            app.MaxEpochsEditField.Value = 20;
            app.InitialLearnRateEditFieldLabel = uilabel(app.Panel);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.Panel, 'numeric');
            app.InitialLearnRateEditField.Value = 0.0001;
            app.MiniBatchSizeEditFieldLabel = uilabel(app.Panel);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.Panel, 'numeric');
            app.MiniBatchSizeEditField.Limits = [2 Inf];
            app.MiniBatchSizeEditField.Value = 8;
            app.ValidationFrequencyEditFieldLabel = uilabel(app.Panel);
            app.ValidationFrequencyEditFieldLabel.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField = uieditfield(app.Panel, 'numeric');
            app.ValidationFrequencyEditField.Limits = [1 Inf];
            app.ValidationFrequencyEditField.Value = 100;
            app.VerboseFrequencyEditFieldLabel = uilabel(app.Panel);
            app.VerboseFrequencyEditFieldLabel.Text = 'VerboseFrequency';
            app.VerboseFrequencyEditField = uieditfield(app.Panel, 'numeric');
            app.VerboseFrequencyEditField.Limits = [1 Inf];
            app.VerboseFrequencyEditField.Value = 50;
            app.ButtonGroup = uibuttongroup(app.Train_Panel);
            app.ButtonGroup.Title = 'Button Group';
            app.YOLO4Button_3 = uiradiobutton(app.ButtonGroup);
            app.YOLO4Button_3.Text = 'YOLO 4';
            app.YOLO4Button_3.Value = true;
            app.YOLOXButton_3 = uiradiobutton(app.ButtonGroup);
            app.YOLOXButton_3.Text = 'YOLO X';
            app.ButtonGroup2 = uibuttongroup(app.Train_Panel);
            app.ButtonGroup2.Title = 'Button Group2';
            app.ADAMButton = uiradiobutton(app.ButtonGroup2);
            app.ADAMButton.Text = 'ADAM';
            app.ADAMButton.Value = true;
            app.SGDMButton = uiradiobutton(app.ButtonGroup2);
            app.SGDMButton.Text = 'SGDM';
            app.BackButton = uiimage(app.Train_Panel);
            app.BackButton.ImageClickedFcn = createCallbackFcn(app, @BackButtonImageClicked, true);
            app.BackButton.ImageSource = fullfile(pathToMLAPP, 'images', 'atras.png');
            app.SelectDirectoryButton = uibutton(app.Train_Panel, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.Model_selector_pannel = uipanel(app.UIFigure);
            app.Image4_2 = uiimage(app.Model_selector_pannel);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'images', 'landscape-background-mountains-with-sunset-illustration.jpg');
            app.NextButton = uibutton(app.Model_selector_pannel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @main_model_selected_pushed, true);
            app.NextButton.Text = 'Next';
            app.SelectmaindetectorLabel = uilabel(app.Model_selector_pannel);
            app.SelectmaindetectorLabel.Text = 'Select main detector';
            app.YOLOXButton = uibutton(app.Model_selector_pannel, 'state');
            app.YOLOXButton.Text = 'YOLO X';
            app.YOLO4Button = uibutton(app.Model_selector_pannel, 'state');
            app.YOLO4Button.Text = 'YOLO 4';
            app.SelectamodelLabel = uilabel(app.Model_selector_pannel);
            app.SelectamodelLabel.Visible = 'off';
            app.SelectamodelLabel.Text = 'Select a model';
            app.BackButton_2 = uiimage(app.Model_selector_pannel);
            app.BackButton_2.ImageClickedFcn = createCallbackFcn(app, @BackButton_2ImageClicked, true);
            app.BackButton_2.ImageSource = fullfile(pathToMLAPP, 'images', 'atras.png');
            app.MyModelsButton = uibutton(app.Model_selector_pannel, 'state');
            app.MyModelsButton.ValueChangedFcn = createCallbackFcn(app, @MyModelsButtonValueChanged, true);
            app.MyModelsButton.Text = 'MY MODELS';
            app.MENUPanel = uipanel(app.UIFigure);
            app.MENUPanel.Title = 'MENU';
            app.MENUPanel.Visible = 'off';
            app.Image2 = uiimage(app.MENUPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'landscape-background-mountains-with-sunset-illustration.jpg');
            app.EntrenarButton = uibutton(app.MENUPanel, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.ClasificarButton = uibutton(app.MENUPanel, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @Classify_Button_pushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel = uipanel(app.UIFigure);
            app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel.Title = 'RECONOCIMIENTO DE PERSONAS EN ENTORNOS NATURALES';
            app.Image = uiimage(app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', '311196969565.jpg');
            app.STARTButton = uibutton(app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtomPushed, true);
            app.STARTButton.Text = 'START';
            app.Humandetector2JNLabel = uilabel(app.RECONOCIMIENTODEPERSONASENENTORNOSNATURALESPanel);
            app.Humandetector2JNLabel.Text = 'Human detector 2JN';
            app.ClassificationPanel = uipanel(app.UIFigure);
            app.ClassificationPanel.Title = 'Classification';
            app.ClassificationPanel.Visible = 'off';
            app.Image3 = uiimage(app.ClassificationPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'abstract-bluish-paint-background-wallpaper.jpg');
            app.Image_to_detect = uiimage(app.ClassificationPanel);
            app.Image_to_detect.ImageSource = fullfile(pathToMLAPP, '15.jpg');
            app.DetectButton = uibutton(app.ClassificationPanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButton_Pushed, true);
            app.DetectButton.Icon = fullfile(pathToMLAPP, 'images', 'pngtree-vector-star-icon-png-image_5169247.jpg');
            app.DetectButton.Text = 'Detect';
            app.BoundingBoxcolorDropDownLabel = uilabel(app.ClassificationPanel);
            app.BoundingBoxcolorDropDownLabel.Text = 'Bounding Box color';
            app.BoundingBoxcolorDropDown = uidropdown(app.ClassificationPanel);
            app.BoundingBoxcolorDropDown.Items = {'blue', 'green', 'red', 'cyan', 'magenta', 'yellow', 'black', 'white', 'b', 'k'};
            app.BoundingBoxcolorDropDown.ValueChangedFcn = createCallbackFcn(app, @BB_color_changed, true);
            app.BoundingBoxcolorDropDown.Value = 'yellow';
            app.LoadingdetectorLabel = uilabel(app.ClassificationPanel);
            app.LoadingdetectorLabel.Visible = 'off';
            app.LoadingdetectorLabel.Text = 'Loading detector...';
            app.ReadingimageLabel = uilabel(app.ClassificationPanel);
            app.ReadingimageLabel.Visible = 'off';
            app.ReadingimageLabel.Text = 'Reading image...';
            app.FindingpeopleinimageLabel = uilabel(app.ClassificationPanel);
            app.FindingpeopleinimageLabel.Visible = 'off';
            app.FindingpeopleinimageLabel.Text = 'Finding people in image...';
            app.GeneratingboundingboxesLabel = uilabel(app.ClassificationPanel);
            app.GeneratingboundingboxesLabel.Visible = 'off';
            app.GeneratingboundingboxesLabel.Text = 'Generating bounding boxes...';
            app.HumanThresholdSliderLabel = uilabel(app.ClassificationPanel);
            app.HumanThresholdSliderLabel.Text = 'Human Threshold';
            app.HumanThresholdSlider = uislider(app.ClassificationPanel, 'range');
            app.HumanThresholdSlider.Limits = [0 1];
            app.HumanThresholdSlider.Value = [0 1];
            app.DownloadImageButton = uibutton(app.ClassificationPanel, 'push');
            app.DownloadImageButton.ButtonPushedFcn = createCallbackFcn(app, @Download_button_pushed, true);
            app.DownloadImageButton.Visible = 'off';
            app.DownloadImageButton.Text = 'Download Image';
            app.LoadcustomimageButton = uibutton(app.ClassificationPanel, 'push');
            app.LoadcustomimageButton.ButtonPushedFcn = createCallbackFcn(app, @Load_custom_image_pushed, true);
            app.LoadcustomimageButton.Text = 'Load custom image';
            app.TotalPeopleLabel = uilabel(app.ClassificationPanel);
            app.TotalPeopleLabel.Text = 'Personas detectadas:';
            app.AvgConfidenceLabel = uilabel(app.ClassificationPanel);
            app.AvgConfidenceLabel.Text = 'Confianza promedio:';
            app.StdDevLabel = uilabel(app.ClassificationPanel);
            app.StdDevLabel.Text = 'Desviación estándar:';
            app.AmpliadaImage = uiimage(app.ClassificationPanel);
            app.AmpliadaImage.Visible = 'off';
            app.AnteriorButton = uiimage(app.ClassificationPanel);
            app.AnteriorButton.ImageClickedFcn = createCallbackFcn(app, @AnteriorButtonImageClicked, true);
            app.AnteriorButton.Visible = 'off';
            app.AnteriorButton.ImageSource = fullfile(pathToMLAPP, 'images', 'atras.png');
            app.SiguienteButton = uiimage(app.ClassificationPanel);
            app.SiguienteButton.ImageClickedFcn = createCallbackFcn(app, @SiguienteButtonImageClicked, true);
            app.SiguienteButton.Visible = 'off';
            app.SiguienteButton.ImageSource = fullfile(pathToMLAPP, 'images', 'siguiente.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end